classdef LBP_HOG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        Button2          matlab.ui.control.Button
        Button           matlab.ui.control.Button
        HOGLabel_2       matlab.ui.control.Label
        LBPLabel_2       matlab.ui.control.Label
        OriginalImage_5  matlab.ui.control.Image
        OriginalImage_4  matlab.ui.control.Image
        HOGLabel         matlab.ui.control.Label
        LBPLabel         matlab.ui.control.Label
        OriginalImage_3  matlab.ui.control.Image
        OriginalImage_2  matlab.ui.control.Image
        Label_2          matlab.ui.control.Label
        TargetImage      matlab.ui.control.Image
        Label            matlab.ui.control.Label
        OriginalImage    matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        % LBP特征提取函数
        function lbpImage = extractLBPFeaturesCustom(image)
            [rows, cols] = size(image);
            lbpImage = zeros(rows, cols, 'uint8'); % 使用uint8减少内存
            for i = 2:rows-1
                for j = 2:cols-1
                    center = image(i, j);
                    binaryPattern = [image(i-1, j-1) >= center, image(i-1, j) >= center, image(i-1, j+1) >= center, ...
                                     image(i, j+1) >= center, image(i+1, j+1) >= center, image(i+1, j) >= center, ...
                                     image(i+1, j-1) >= center, image(i, j-1) >= center];
                    lbpImage(i, j) = bi2de(binaryPattern, 'left-msb');
                end
            end
        end
        % HOG特征提取函数
        function hogFeatures = extractHOGFeaturesCustom(image)
            % Sobel算子计算梯度
            Gx = [-1 0 1; -2 0 2; -1 0 1];
            Gy = Gx';
            gradX = conv2(double(image), Gx, 'same');
            gradY = conv2(double(image), Gy, 'same');
            
            % 计算梯度幅值和方向
            magnitude = sqrt(gradX.^2 + gradY.^2);
            angle = atan2(gradY, gradX);
            
            % 将角度转换为0到180度
            angle = angle * (180 / pi);
            angle(angle < 0) = angle(angle < 0) + 180;
            
            % 分块计算HOG特征
            cellSize = 8;
            [rows, cols] = size(image);
            hogFeatures = zeros(rows, cols);
            for i = 1:cellSize:rows-cellSize
                for j = 1:cellSize:cols-cellSize
                    blockMag = magnitude(i:i+cellSize-1, j:j+cellSize-1);
                    blockAngle = angle(i:i+cellSize-1, j:j+cellSize-1);
                    hist = zeros(1, 9);
                    for k = 1:cellSize
                        for l = 1:cellSize
                            bin = floor(blockAngle(k, l) / 20) + 1;
                            if bin > 9
                                bin = 9;
                            end
                            hist(bin) = hist(bin) + blockMag(k, l);
                        end
                    end
                    % 将直方图值放入特征图中
                    hogFeatures(i:i+cellSize-1, j:j+cellSize-1) = sum(hist);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                grayImg = rgb2gray(img);
                % 使用OTSU方法进行图像分割
                level = graythresh(grayImg);
                TargetImg = imbinarize(grayImg, level);
                
                rgbTargetImg = cat(3, TargetImg * 255, TargetImg * 255, TargetImg * 255);
                app.TargetImage.ImageSource = rgbTargetImg; 

            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.TargetImage = uiimage(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '目标';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '特征提取';
            app.OriginalImage_2 = uiimage(app.Panel);
            app.OriginalImage_3 = uiimage(app.Panel);
            app.LBPLabel = uilabel(app.Panel);
            app.LBPLabel.Text = '原图LBP特征提取';
            app.HOGLabel = uilabel(app.Panel);
            app.HOGLabel.Text = '原图HOG特征提取';
            app.OriginalImage_4 = uiimage(app.Panel);
            app.OriginalImage_5 = uiimage(app.Panel);
            app.LBPLabel_2 = uilabel(app.Panel);
            app.LBPLabel_2.Text = '目标LBP特征提取';
            app.HOGLabel_2 = uilabel(app.Panel);
            app.HOGLabel_2.Text = '目标HOG特征提取';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LBP_HOG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end