classdef histogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel           matlab.ui.container.Panel
        Label_3         matlab.ui.control.Label
        GrayImage       matlab.ui.control.Image
        Label           matlab.ui.control.Label
        OriginalImage   matlab.ui.control.Image
        UIAxesHistgram  matlab.ui.control.UIAxes
        Panel_2         matlab.ui.container.Panel
        Button          matlab.ui.control.Button
        ResImage        matlab.ui.control.Image
        UIAxes_2        matlab.ui.control.UIAxes
        Panel_3         matlab.ui.container.Panel
        Button_2        matlab.ui.control.Button
        Res2Image       matlab.ui.control.Image
        Label_4         matlab.ui.control.Label
        TargetImage     matlab.ui.control.Image
        UIAxes_3        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        function mapping_table = histogram_matching(app, input_histogram, target_histogram)
            % 获取累积直方图
            input_cumulative = cumsum(input_histogram) / sum(input_histogram);
            target_cumulative = cumsum(target_histogram) / sum(target_histogram);

            % 映射表
            mapping_table = zeros(256, 1, 'uint8');
            
            % 进行直方图匹配
            for i = 1:256
                [~, index] = min(abs(target_cumulative - input_cumulative(i)));
                mapping_table(i) = index - 1;
            end
        end
        function equalImage = equalization(~, rgbImage)
            
            histgram = imhist(rgbImage);
            [h, w] = size(rgbImage);
            equalImage = zeros(h, w, 'uint8');
            s = zeros(256);
            s(1) = histgram(1);
            % 计算累积直方图
            for t=2:256
                s(t) = s(t-1) + histgram(t);
            end
            
            for x = 1:w
                for y = 1:h
                    equalImage(y,x) = uint8(255*s(rgbImage(y,x)+1)/(w*h));
                end
            end
        end
        
        %灰度直方图
        function histogram = gray_histogram(~, image)
            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;	
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
        end
        % 绘制直方图
        function draw_histogram1(app, histogram)
            axesHandle = app.UIAxesHistgram;
            bar(axesHandle, histogram);
        end
        function draw_histogram2(app, histogram)
            axesHandle = app.UIAxes_2;
            bar(axesHandle, histogram);
        end
        function draw_histogram3(app, histogram)
            axesHandle = app.UIAxes_3;
            bar(axesHandle, histogram);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: OriginalImage
        function OriginalImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.OriginalImage.ImageSource = img;
                
                gray_image = rgb2gray(img);
                app.GrayImage.ImageSource = cat(3, gray_image, gray_image, gray_image);
                histogram = gray_histogram(app, gray_image);
                draw_histogram1(app, histogram);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            gray_image = rgb2gray(app.OriginalImage.ImageSource);
            image=equalization(app, gray_image);
            app.ResImage.ImageSource = cat(3,image,image,image);
            histogram = gray_histogram(app, rgb2gray(app.ResImage.ImageSource));
            draw_histogram2(app, histogram);
        end

        % Image clicked function: TargetImage
        function TargetImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; '*.*', 'All Files (*.*)'}, 'Select an image file');
        
            % Check if the user selected a file
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled the operation');
            else
                imagePath = fullfile(pathname, filename);

                img = imread(imagePath);

                app.TargetImage.ImageSource = img;

            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            input_image = rgb2gray(app.OriginalImage.ImageSource);
            target_image = rgb2gray(app.TargetImage.ImageSource);
            target_histogram = gray_histogram(app, target_image);
            input_histogram = gray_histogram(app, input_image);

            % 调用直方图匹配函数
            mapping_table = app.histogram_matching(input_histogram, target_histogram);

            % 应用映射表到输入图像
            matched_image = intlut(input_image, mapping_table);

            app.Res2Image.ImageSource = cat(3, matched_image, matched_image, matched_image);

            % 计算并绘制匹配后的灰度直方图
            matchedHistogram = gray_histogram(app, app.Res2Image.ImageSource);
            draw_histogram3(app, matchedHistogram);
        
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button down function: UIAxesHistgram
        function UIAxesHistgramButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '直方图规定化';
            app.UIAxes_3 = uiaxes(app.Panel_3);
            title(app.UIAxes_3, '规定化灰度直方图')
            xlabel(app.UIAxes_3, '灰度值')
            ylabel(app.UIAxes_3, '频次')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.TargetImage = uiimage(app.Panel_3);
            app.TargetImage.ImageClickedFcn = createCallbackFcn(app, @TargetImageClicked, true);
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '规定化参照图片';
            app.Res2Image = uiimage(app.Panel_3);
            app.Button_2 = uibutton(app.Panel_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '直方图匹配';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '直方图均衡化';
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, '均衡化灰度直方图')
            xlabel(app.UIAxes_2, '灰度值')
            ylabel(app.UIAxes_2, '频次')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.ResImage = uiimage(app.Panel_2);
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '直方图均衡化';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '灰度图';
            app.UIAxesHistgram = uiaxes(app.Panel);
            title(app.UIAxesHistgram, '原图灰度直方图')
            xlabel(app.UIAxesHistgram, '灰度值')
            ylabel(app.UIAxesHistgram, '频次')
            zlabel(app.UIAxesHistgram, 'Z')
            app.UIAxesHistgram.ButtonDownFcn = createCallbackFcn(app, @UIAxesHistgramButtonDown, true);
            app.OriginalImage = uiimage(app.Panel);
            app.OriginalImage.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '原图';
            app.GrayImage = uiimage(app.Panel);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '灰度图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end